classdef Camima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamimaMain           matlab.ui.Figure
        MainTabGroup         matlab.ui.container.TabGroup
        HOMETab              matlab.ui.container.Tab
        CameraPanel          matlab.ui.container.Panel
        CameraBrowserButton  matlab.ui.control.Button
        CameraSettingButton  matlab.ui.control.Button
        ImastPanel           matlab.ui.container.Panel
        Tab2                 matlab.ui.container.Tab
        Tab3                 matlab.ui.container.Tab
        Status               matlab.ui.control.EditField
    end


    methods (Access = private)
    
        function results = ResetAllCameras(app)
            %results = 0;
            app.Status.Value = 'Reseting all cameras...';
            imaqreset; %reset all cameras from image acquisition tool box
            app.Status.Value = 'All cameras has been reset!';
            results = 1;
        end
        
    end


    methods (Access = private)

        % Size changed function: CamimaMain
        function CamimaMainSizeChanged(app, event)
           % h = gcbo;
           % position = h.Position;
            position = app.CamimaMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 22 position(3) position(4)-22];
        end

        % Size changed function: HOMETab
        function HOMETabSizeChanged(app, event)
            position = app.HOMETab.Position;
            if ~isempty(app.CameraPanel)
                app.CameraPanel.Position = [0 0 position(3)/5 position(4)];
            end
            if ~isempty(app.ImastPanel)
                app.ImastPanel.Position = [position(3)/5 0 position(3)/5 position(4)];
            end
        end

        % Size changed function: CameraPanel
        function CameraPanelSizeChanged(app, event)
            position = app.CameraPanel.Position;
            if ~isempty(app.CameraBrowserButton)
                app.CameraBrowserButton.Position = [5 5 (position(3)-15)/2 position(4)-30];
            end
            if ~isempty(app.CameraSettingButton)
                app.CameraSettingButton.Position = [(position(3)-15)/2+10 5 (position(3)-15)/2 position(4)-30];
            end
        end

        % Button pushed function: CameraBrowserButton
        function CameraBrowserButtonPushed(app, event)
            position = app.CamimaMain.Position;
            import uiextras.jTree.*;
            browser = figure('MenuBar','none','Name','Camera Browser','NumberTitle','off',...
                'ToolBar','none','Position',[position(1) position(2)-500 250 500]);
            toolbar = uitoolbar(browser);
            b = uipushtool('Parent',toolbar,'TooltipString','Refresh all cameras');
            t = Tree('Parent',browser);
            Node1 = TreeNode('Name','Node_1_original','Parent',t.Root);
            Node1 = TreeNode('Name','Node_1','Parent',t.Root);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamimaMain = uifigure;
            app.CamimaMain.Name = 'Camima';
            app.CamimaMain.SizeChangedFcn = createCallbackFcn(app, @CamimaMainSizeChanged, true);
            setAutoResize(app, app.CamimaMain, true)
            app.MainTabGroup = uitabgroup(app.CamimaMain);
            app.HOMETab = uitab(app.MainTabGroup);
            app.HOMETab.SizeChangedFcn = createCallbackFcn(app, @HOMETabSizeChanged, true);
            app.HOMETab.Title = 'HOME';
            app.HOMETab.Units = 'pixels';
            app.CameraPanel = uipanel(app.HOMETab);
            app.CameraPanel.Title = 'Camera';
            app.CameraPanel.SizeChangedFcn = createCallbackFcn(app, @CameraPanelSizeChanged, true);
            app.CameraBrowserButton = uibutton(app.CameraPanel, 'push');
            app.CameraBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @CameraBrowserButtonPushed, true);
            app.CameraBrowserButton.Icon = 'cameraIcon.png';
            app.CameraBrowserButton.Text = {'Camera'; 'Browser'};
            app.CameraSettingButton = uibutton(app.CameraPanel, 'push');
            app.CameraSettingButton.Icon = 'setting.png';
            app.CameraSettingButton.Text = {'Camera'; 'Setting'};
            app.ImastPanel = uipanel(app.HOMETab);
            app.ImastPanel.Title = 'Image Stream';
            app.Tab2 = uitab(app.MainTabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
            app.Tab3 = uitab(app.MainTabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3.Units = 'pixels';
            app.Status = uieditfield(app.CamimaMain, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Camima()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamimaMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamimaMain)
        end
    end
end