classdef Vuima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VuimaMain                     matlab.ui.Figure
        MainTabGroup                  matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        LinkingPanel                  matlab.ui.container.Panel
        LinkingLamp                   matlab.ui.control.Lamp
        imastDropDownLabel            matlab.ui.control.Label
        imastDropDown                 matlab.ui.control.DropDown
        LinkButton                    matlab.ui.control.Button
        SavingPanel                   matlab.ui.container.Panel
        Browser                       matlab.ui.control.Button
        SelectFolderEditFieldPGLabel  matlab.ui.control.Label
        SelectFolder                  matlab.ui.control.EditField
        FileNameEditFieldPGLabel      matlab.ui.control.Label
        FileName                      matlab.ui.control.EditField
        DoneButton                    matlab.ui.control.Button
        AutoSave                      matlab.ui.control.CheckBox
        CheckImagePanel               matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        CheckButton                   matlab.ui.control.Button
        Spinner                       matlab.ui.control.Spinner
        ImJButton                     matlab.ui.control.Button
        ViewTab                       matlab.ui.container.Tab
        LUTPanel                      matlab.ui.container.Panel
        LUTDropDown                   matlab.ui.control.DropDown
        LUTButton                     matlab.ui.control.Button
        FittingData                   matlab.ui.control.Table
        Status                        matlab.ui.control.EditField
        FittingPanel                  matlab.ui.container.Panel
        ImageShow                     matlab.ui.control.UIAxes
        fittingY                      matlab.ui.control.UIAxes
        fittingX                      matlab.ui.control.UIAxes
        FittingSettingPanel           matlab.ui.container.Panel
        Auto                          matlab.ui.control.CheckBox
        FitButton                     matlab.ui.control.Button
        FittingModeDropDown           matlab.ui.control.DropDown
        FittingSettingButton          matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        Light                         matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        camima % main camima handle
        imast % image stream
        curimage %present image data, used to show and do fitting
        Equation % fit equation
        opt % fit options
        otherimage % is a struct including UserDifined images, like BG, Light, Atom and more
        curFileNum %current file number
        curBin %binning of current image
    end
    
    methods (Access = public)
        
        function [fitresult, gof] = dofit(~,XData, YData, ZData, equation, opts)
            %% Fit
            [xData, yData, zData] = prepareSurfaceData(XData, YData, ZData);
            
            % Set up fittype and options.
            ft = fittype(equation{1}, 'independent', {'x', 'y'}, 'dependent', 'z' );
                                    
            % Fit model to data.
            [fitresult, gof] = fit( [xData, yData], zData, ft, opts );
            
            %make fitting parallel
            %f = parfeval(@fit,2,[xData, yData], zData, ft, opts);
            %[fitresult, gof] = fetchOutputs(f);
        end
        

        function [Xdata, Ydata, Zdata, peak, xc, yc, xs, ys] = image2data(~, data)
            %data = app.curimage;
            [xs,ys] = size(data);           
            Zdata = data(:)';         
            Xdata = repmat(1:xs,1,ys);
            Ydata = repelem(1:ys,xs);
            % peak and canter more info from preprocess
            [peak,peakPosition] = max(data(:));
            [xc,yc] = ind2sub(size(data),peakPosition);
        end
        
        function fit(app)
           %run fitting in parallel
           %parfeval(@fitCore,0,app);
           fitCore(app);
        end
        
        function fitCore(app)
             %get model to fitting
            model = app.FittingModeDropDown.Value;
            
            switch model
                case 'Customed'
                    fitCustomed(app);
                case 'Gaussian'
                    fitGaussian(app);
                case 'Double Gaussian'
                    fitDoubleGaussian(app);
            end            
        end
        
        function saveimage(app)
            
            imwrite(app.otherimage.IMG1,[app.SelectFolder.Value '\' num2str(app.curFileNum,'%03d') '-' app.FileName.Value '-1.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG2,[app.SelectFolder.Value '\' num2str(app.curFileNum,'%03d') '-' app.FileName.Value '-2.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG3,[app.SelectFolder.Value '\' num2str(app.curFileNum,'%03d') '-' app.FileName.Value '-3.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            
            imwrite(uint16(app.curimage),[app.SelectFolder.Value '\' num2str(app.curFileNum,'%03d') '-' app.FileName.Value '-OD.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            %refresh spinner
            app.Spinner.Value = app.curFileNum;
            app.curFileNum = app.curFileNum + 1;
        end
        
        function fitGaussian(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %sigx and sigy
            sigx = 20;
            sigy = 10;
            
            %Status show fitting...
            app.Status.Value = 'Start fitting... ...';
            
            %set gaussian equation
            equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 Inf Inf xs ys];
            opts.Lower = [0 30 0 0 1 1];
            opts.StartPoint = [200 peak sigx sigy xc yc];
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %reconstruct function and data for showing
            coNumber = numcoeffs(fitresult);
            coName = coeffnames(fitresult);
            coValue = coeffvalues(fitresult);
            
            % show fitting result
            app.FittingData.Data = [coName num2cell(coValue')];
            
            %%save fitting result
            %get fit-filename
            fitfilename = [app.SelectFolder.Value '\' app.FileName.Value '.csv'];            
            dlmwrite(fitfilename,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(4),app.curBin.y*coValue(3),coValue(6),coValue(5)],'-append');
            
            %%plot and compare fitting result
            %construct coeff string for plotting
            costr = coName{1};
            if coNumber > 1
                for i = 2:coNumber
                    costr = [costr ',' coName{i}];
                end
            end
            
            %build function for plot
            fy = str2func(vectorize(['@(x) @(' costr ') @(y)' formula(fitresult)]));
            fx = str2func(vectorize(['@(y) @(' costr ') @(x)' formula(fitresult)]));
            
            %plot x-direction 
            
            fytemp = fy(fitresult.xc);
            arg = num2cell(coValue);
            f = fytemp(arg{:});
            
            app.fittingX.XLim = [0.5 ys+0.5];
            plot(app.fittingX,1:ys,f(1:ys),1:ys,app.curimage(xc,:));
            
            %plot y-direction
            fxtemp = fx(fitresult.yc);
            arg = num2cell(coValue);
            f = fxtemp(arg{:});
            
            app.fittingY.YLim = [0.5 xs+0.5];
            app.fittingY.YDir = 'reverse';
            plot(app.fittingY,f(1:xs),1:xs,app.curimage(:,yc)',1:xs);
        end
        
        function fitCustomed(app)
            
            %do fitting
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, app.Equation, app.opt);
            app.Status.Value = 'Fitting done!';
            
            %reconstruct function and data for showing
            coNumber = numcoeffs(fitresult);
            coName = coeffnames(fitresult);
            coValue = coeffvalues(fitresult);
            
            % show fitting result
            app.FittingData.Data = [coName num2cell(coValue')];
            
            %construct coeff string
            costr = coName{1};
            if coNumber > 1
                for i = 2:coNumber
                    costr = [costr ',' coName{i}];
                end
            end
            %build function for plot
            fy = str2func(vectorize(['@(x) @(' costr ') @(y)' formula(fitresult)]));
            fx = str2func(vectorize(['@(y) @(' costr ') @(x)' formula(fitresult)]));
            
            %plot x-direction
            
            fytemp = fy(fitresult.xc);
            arg = num2cell(coValue);
            f = fytemp(arg{:});
            
            app.fittingX.XLim = [0.5 ys+0.5];
            plot(app.fittingX,1:ys,f(1:ys),1:ys,app.curimage(xc,:));


            %plot y-direction
            fxtemp = fx(fitresult.yc);
            arg = num2cell(coValue);
            f = fxtemp(arg{:});
            
            app.fittingY.YLim = [0.5 xs+0.5];
            app.fittingY.YDir = 'reverse';
            plot(app.fittingY,f(1:xs),1:xs,app.curimage(:,yc)',1:xs);
        end
        
    end


    methods (Access = private)

        % Size changed function: VuimaMain
        function VuimaMainSizeChanged(app, event)
            position = app.VuimaMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 position(4)-125 position(3) 125];
            app.FittingData.Position = [0 22 150 position(4)-22-125];
            app.FittingPanel.Position = [150 22 position(3)-150 position(4)-22-125];
        end

        % Size changed function: HomeTab
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.LinkingPanel.Position = [0 0 150 position(4)];
            app.SavingPanel.Position = [150 0 330 position(4)];
            app.CheckImagePanel.Position = [480 0 190 position(4)];
        end

        % Size changed function: ViewTab
        function ViewTabSizeChanged(app, event)
            position = app.ViewTab.Position;
            app.LUTPanel.Position = [0 0 300 position(4)];
        end

        % Size changed function: FittingPanel
        function FittingPanelSizeChanged(app, event)
            position = app.FittingPanel.Position;
            if ~isempty(app.ImageShow)
                app.ImageShow.Position = [0 130 position(3)-130 position(4)-130];
            end
            
            if ~isempty(app.fittingX)
                app.fittingX.Position = [0 0 position(3)-130 130];
            end
            if ~isempty(app.fittingY)
                app.fittingY.Position = [position(3)-130 130 130 position(4)-130];
            end
            if ~isempty(app.FittingSettingPanel)
                app.FittingSettingPanel.Position = [position(3)-130 0 130 130];
            end
        end

        % Button pushed function: LinkButton
        function LinkButtonPushed(app, event)
            if strcmp(app.imastDropDown.Value,'Check')
                
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Please Check image and data!';
                
            else
                %get selected imast
                Imast = app.imastDropDown.Value;
                %get camima main app handle
                mainapp = app.camima;
                %put local app handle to main app
                mainapp.selectedimast.(Imast) = app;
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Successful Linking!';
                mainapp.Status.Value = 'one vuima successful linking in!';
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            fit(app);  
        end

        % Button pushed function: FittingSettingButton
        function FittingSettingButtonPushed(app, event)
            if ~isempty(app.Equation)&&~isempty(app.opt)
                fitset = Fitset;
                fitset.vuima = app;
                fitset.Equation.Value = app.Equation;
                fitset.Algorithm.Value = app.opt.Algorithm;
                fitset.DiffMaxChange.Value = app.opt.DiffMaxChange;
                fitset.DiffMinChange.Value = app.opt.DiffMinChange;
                
                coefficients = symvar(app.Equation{1});
                coefficients = coefficients(~strcmp(coefficients,'x'));
                coefficients = coefficients(~strcmp(coefficients,'y'));
                %create coTable
                StartPoint = num2cell(app.opt.StartPoint');
                Lower = num2cell(app.opt.Lower');
                Upper = num2cell(app.opt.Upper');
                %num2cell(true(7,1));
                fitset.CoTable.Enable = 'on';
                fitset.CoTable.Data = [coefficients StartPoint Lower Upper];
                
                fitset.MaxFunEvals.Value = app.opt.MaxFunEvals;
                fitset.MaxIter.Value = app.opt.MaxIter;
                fitset.Robust.Value = app.opt.Robust;
                fitset.TolFun.Value = app.opt.TolFun;
                fitset.TolX.Value = app.opt.TolX;
            else
                fitset = Fitset;
                fitset.vuima = app;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %check existing file in folder with this file name...
            %get the number of file then refresh gui
            files = dir([app.SelectFolder.Value '\*' app.FileName.Value '-1.tiff']);
            if ~isempty(files)
                lastFileNameSP = split(files(end).name,'-');
                lastFileNumber = str2double(lastFileNameSP{1});
                %show on
                app.Spinner.Value = lastFileNumber;
                %set currrent
                app.curFileNum = lastFileNumber;
            else
                 %show on
                app.Spinner.Value = 0;
                %set currrent
                app.curFileNum = 1;
            end
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            value = app.DropDown.Value;
            
            switch value
                case 'Atom'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            app.curimage = imread(imaname);
            imainfo = imfinfo(imaname);
            Bin = split(imainfo.ImageDescription,'-');
            app.curBin.x = str2double(Bin(1));
            app.curBin.y = str2double(Bin(2));
            
            %set show aspect ratio
            imgsize = size(app.curimage);
            app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
            imagesc(app.ImageShow,app.curimage);
        end

        % Button pushed function: Browser
        function BrowserButtonPushed(app, event)
            dname = uigetdir('D:\data');
            if dname
                app.SelectFolder.Value = dname;
            end
        end

        % Button pushed function: ImJButton
        function ImJButtonPushed(app, event)
            value = app.DropDown.Value;
              
            switch value
                case 'Atom'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            winopen(path);
        end

        % Button pushed function: LUTButton
        function LUTButtonPushed(app, event)
            value = app.LUTDropDown.Value;
            
            switch value
                case 'JET'
                    colormap(app.ImageShow,jet);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VuimaMain = uifigure;
            app.VuimaMain.Name = 'Vuima';
            app.VuimaMain.SizeChangedFcn = createCallbackFcn(app, @VuimaMainSizeChanged, true);
            setAutoResize(app, app.VuimaMain, true)
            app.MainTabGroup = uitabgroup(app.VuimaMain);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.SizeChangedFcn = createCallbackFcn(app, @HomeTabSizeChanged, true);
            app.HomeTab.Title = 'Home';
            app.HomeTab.Units = 'pixels';
            app.LinkingPanel = uipanel(app.HomeTab);
            app.LinkingPanel.Title = 'Linking';
            app.LinkingLamp = uilamp(app.LinkingPanel);
            app.imastDropDownLabel = uilabel(app.LinkingPanel);
            app.imastDropDownLabel.Enable = 'off';
            app.imastDropDownLabel.Text = 'imast';
            app.imastDropDown = uidropdown(app.LinkingPanel);
            app.imastDropDown.Items = {'Check'};
            app.imastDropDown.Value = 'Check';
            app.LinkButton = uibutton(app.LinkingPanel, 'push');
            app.LinkButton.ButtonPushedFcn = createCallbackFcn(app, @LinkButtonPushed, true);
            app.LinkButton.Text = 'Link';
            app.SavingPanel = uipanel(app.HomeTab);
            app.SavingPanel.Title = 'Saving';
            app.Browser = uibutton(app.SavingPanel, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.Browser.Enable = 'off';
            app.Browser.Text = 'Browser';
            app.SelectFolderEditFieldPGLabel = uilabel(app.SavingPanel);
            app.SelectFolderEditFieldPGLabel.Text = 'Select  Folder';
            app.SelectFolder = uieditfield(app.SavingPanel, 'text');
            app.SelectFolder.Enable = 'off';
            app.FileNameEditFieldPGLabel = uilabel(app.SavingPanel);
            app.FileNameEditFieldPGLabel.Text = 'File Name';
            app.FileName = uieditfield(app.SavingPanel, 'text');
            app.FileName.Enable = 'off';
            app.DoneButton = uibutton(app.SavingPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.AutoSave = uicheckbox(app.SavingPanel);
            app.AutoSave.Enable = 'off';
            app.AutoSave.Text = {'Auto'; 'Save'};
            app.CheckImagePanel = uipanel(app.HomeTab);
            app.CheckImagePanel.Title = 'Check Image';
            app.DropDown = uidropdown(app.CheckImagePanel);
            app.DropDown.Items = {'Atom', 'Light', 'Bg', 'OD'};
            app.DropDown.Value = 'Atom';
            app.CheckButton = uibutton(app.CheckImagePanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Spinner = uispinner(app.CheckImagePanel);
            app.ImJButton = uibutton(app.CheckImagePanel, 'push');
            app.ImJButton.ButtonPushedFcn = createCallbackFcn(app, @ImJButtonPushed, true);
            app.ImJButton.Text = 'ImJ';
            app.ViewTab = uitab(app.MainTabGroup);
            app.ViewTab.SizeChangedFcn = createCallbackFcn(app, @ViewTabSizeChanged, true);
            app.ViewTab.Title = 'View';
            app.ViewTab.Units = 'pixels';
            app.LUTPanel = uipanel(app.ViewTab);
            app.LUTPanel.Title = 'LUT';
            app.LUTDropDown = uidropdown(app.LUTPanel);
            app.LUTDropDown.Items = {'JET', 'Gray', 'Option 3', 'Option 4'};
            app.LUTDropDown.Value = 'JET';
            app.LUTButton = uibutton(app.LUTPanel, 'push');
            app.LUTButton.ButtonPushedFcn = createCallbackFcn(app, @LUTButtonPushed, true);
            app.LUTButton.Text = 'LUT';
            app.FittingData = uitable(app.VuimaMain);
            app.FittingData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FittingData.RowName = {};
            app.Status = uieditfield(app.VuimaMain, 'text');
            app.FittingPanel = uipanel(app.VuimaMain);
            app.FittingPanel.SizeChangedFcn = createCallbackFcn(app, @FittingPanelSizeChanged, true);
            app.ImageShow = uiaxes(app.FittingPanel);
            app.fittingY = uiaxes(app.FittingPanel);
            app.fittingX = uiaxes(app.FittingPanel);
            app.FittingSettingPanel = uipanel(app.FittingPanel);
            app.Auto = uicheckbox(app.FittingSettingPanel);
            app.Auto.Text = 'Auto';
            app.FitButton = uibutton(app.FittingSettingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FittingModeDropDown = uidropdown(app.FittingSettingPanel);
            app.FittingModeDropDown.Items = {'Customed', 'Gaussian', 'Double Gaussian', 'Option 4'};
            app.FittingModeDropDown.Value = 'Customed';
            app.FittingSettingButton = uibutton(app.FittingSettingPanel, 'push');
            app.FittingSettingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingSettingButtonPushed, true);
            app.FittingSettingButton.Text = 'Setting';
            app.StopButton = uibutton(app.FittingSettingPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.Light = uieditfield(app.FittingSettingPanel, 'numeric');
            app.Light.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vuima()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VuimaMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VuimaMain)
        end
    end
end